classdef MatlabTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TESTButton                     matlab.ui.control.Button
        GridLayout7                    matlab.ui.container.GridLayout
        SendMethodLabel                matlab.ui.control.Label
        TextLabel                      matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        SINxID40002Button              matlab.ui.control.Button
        HelloWorldID10001Button        matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        SpeedofdataEditField           matlab.ui.control.NumericEditField
        SpeedofdataEditFieldLabel      matlab.ui.control.Label
        Data_Info_Label                matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        clccloseallButton              matlab.ui.control.Button
        SAVEButton                     matlab.ui.control.Button
        AvailableCOMPortsListBox       matlab.ui.control.ListBox
        AvailableCOMPortsListBoxLabel  matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        LengthOfArrayEditField         matlab.ui.control.NumericEditField
        LengthofarrayEditFieldLabel    matlab.ui.control.Label
        Array1NID50002Button           matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        connectedLamp                  matlab.ui.control.Lamp
        ConnectButton                  matlab.ui.control.Button
        ConnectedEditField             matlab.ui.control.EditField
        ConnectedLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        button % app.button = Connect/Disconnect COM port 
    end
    
    properties (Access = public)
        connect = 0;
        arrSize = 500;
        Fs                      % Vzorkovaci frekvence
        s                       % Serial PORT
        temp1                   % Description
        speed = 115200;
    end

    methods (Access = private)
        
        function SerialPortCallBack010(app, ~, ~)% only one switch for Id
            try
                [iD, nData, xData] = readDataSTM32(app.s);
                if iD==0
                    return
                end
                switch iD
                    case 1010
                        app.ConnectionLabel.Text = string(xData(1:end-1));
                    otherwise
                        app.Data_Info_Label.Text= "ID: " + iD + "  nData: " + nData;               
                        ELM_POOL_Callback(app, iD, nData, xData);
                end
            catch ME
                disp(ME.message) %             rethrow(ME)
            end
        end
        
    end
    

    methods (Access = public)
        
        function AddConsole(app, text)
            app.TextLabel.Text= append(text, newline, app.TextLabel.Text);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
            app.button = true;
            app.AvailableCOMPortsListBox.Items=serialportlist;
            app.ConnectedEditField.Value =  app.AvailableCOMPortsListBox.Value;
            clc;
            load("config.mat", "x", "app_button"); 
            app.ConnectedEditField.Value=x; 
            if app_button==false 
                app.ConnectPushed(app);
            end
            app.Fs=48000;
            app.AddConsole(string(datetime));
            catch ME
                disp(ME.message)    %             rethrow(ME)
            end
        end

        % Callback function: AvailableCOMPortsListBox, 
        % ...and 1 other component
        function availableCOMports(app, event)
            app.ConnectedEditField.Value = app.AvailableCOMPortsListBox.Value;
            app.AvailableCOMPortsListBox.Items=serialportlist;
            app.s = app.ConnectedEditField.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectPushed(app, event)
        try
            if app.button == true  % připojení
                app.ConnectButton.Text = "Disconnect";
                app.button = false;
                app.s = serialport(app.ConnectedEditField.Value, app.speed);
                flush(app.s);
                configureCallback(app.s, "byte", 4, @app.SerialPortCallBack010);
                app.ConnectedEditField.Tooltip = "Baud rate: " + app.s.BaudRate + " [baud]";
                app.connectedLamp.Color = 'green';  % zapnout zelenou
            else  % odpojení
                app.ConnectButton.Text = "Connect";
                app.button = true;
                configureCallback(app.s, "off");
                flush(app.s);
                delete(app.s);
                app.s = [];
                app.ConnectionLabel.Text = "---";
                app.ConnectedEditField.Tooltip = "Baud rate: 0 [baud]";
                app.connectedLamp.Color = 'red';  % červená
            end
        catch ME
            disp(ME.message)
            app.connectedLamp.Color = 'red'; % fallback při chybě
        end
        end

        % Button pushed function: clccloseallButton
        function ClcCloseallButtonPushed(app, event)
            clc; close all; app.TextLabel.Text = "";
        end

        % Button pushed function: SAVEButton
        function SaveCOMButtonPushed(app, event)
            x = app.ConnectedEditField.Value;
            app_button=app.button;
            save("config.mat", "x", "app_button");
        end

        % Button pushed function: HelloWorldID10001Button
        function Button_HelloWorldPushed(app, event)
            ELM_POOL_Extern_Button_ID(app, 40001);
        end

        % Button pushed function: SINxID40002Button
        function Button_SinPushed(app, event)
            ELM_POOL_Extern_Button_ID(app, 40002);
        end

        % Button pushed function: Array1NID50002Button
        function Button5002Pushed(app, event)
            ELM_POOL_Extern_Button_ID(app, 50002);
        end

        % Value changed function: LengthOfArrayEditField
        function LengthOfArrayEditFieldValueChanged(app, event)
            value = app.LengthOfArrayEditField.Value;
            app.arrSize = value;
        end

        % Value changed function: SpeedofdataEditField
        function DataSpeedChanged(app, event)
            value = app.SpeedofdataEditField.Value;
            app.speed = value;
        end

        % Button pushed function: TESTButton
        function TestPushed(app, event)
            testMethod(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ConnectedLabel = uilabel(app.GridLayout2);
            app.ConnectedLabel.Text = 'Connected:';
            app.ConnectedEditField = uieditfield(app.GridLayout2, 'text');
            app.ConnectButton = uibutton(app.GridLayout2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connectedLamp = uilamp(app.GridLayout2);
            app.Array1NID50002Button = uibutton(app.GridLayout, 'push');
            app.Array1NID50002Button.ButtonPushedFcn = createCallbackFcn(app, @Button5002Pushed, true);
            app.Array1NID50002Button.Text = {'Array 1:N'; 'ID: 50002'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LengthofarrayEditFieldLabel = uilabel(app.GridLayout3);
            app.LengthofarrayEditFieldLabel.Text = 'Length of array';
            app.LengthOfArrayEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LengthOfArrayEditField.Limits = [1 20000];
            app.LengthOfArrayEditField.ValueDisplayFormat = '%.0f';
            app.LengthOfArrayEditField.ValueChangedFcn = createCallbackFcn(app, @LengthOfArrayEditFieldValueChanged, true);
            app.LengthOfArrayEditField.Value = 500;
            app.AvailableCOMPortsListBoxLabel = uilabel(app.GridLayout);
            app.AvailableCOMPortsListBoxLabel.Text = 'Available COM Ports';
            app.AvailableCOMPortsListBox = uilistbox(app.GridLayout);
            app.AvailableCOMPortsListBox.ValueChangedFcn = createCallbackFcn(app, @availableCOMports, true);
            app.AvailableCOMPortsListBox.ClickedFcn = createCallbackFcn(app, @availableCOMports, true);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SAVEButton = uibutton(app.GridLayout4, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCOMButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.clccloseallButton = uibutton(app.GridLayout4, 'push');
            app.clccloseallButton.ButtonPushedFcn = createCallbackFcn(app, @ClcCloseallButtonPushed, true);
            app.clccloseallButton.Text = 'clc; close all;';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Data_Info_Label = uilabel(app.GridLayout5);
            app.Data_Info_Label.Tooltip = {'Poslední příchozí data: ID, n bajtů (kromě keep alive 1010:xxx)'};
            app.Data_Info_Label.Text = '';
            app.SpeedofdataEditFieldLabel = uilabel(app.GridLayout5);
            app.SpeedofdataEditFieldLabel.Text = 'Speed of data';
            app.SpeedofdataEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SpeedofdataEditField.Limits = [1 921601];
            app.SpeedofdataEditField.ValueDisplayFormat = '%.0f';
            app.SpeedofdataEditField.ValueChangedFcn = createCallbackFcn(app, @DataSpeedChanged, true);
            app.SpeedofdataEditField.Value = 115200;
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.HelloWorldID10001Button = uibutton(app.GridLayout6, 'push');
            app.HelloWorldID10001Button.ButtonPushedFcn = createCallbackFcn(app, @Button_HelloWorldPushed, true);
            app.HelloWorldID10001Button.Text = {'Hello World'; 'ID: 10001'};
            app.SINxID40002Button = uibutton(app.GridLayout6, 'push');
            app.SINxID40002Button.ButtonPushedFcn = createCallbackFcn(app, @Button_SinPushed, true);
            app.SINxID40002Button.Text = {'SIN(x)'; 'ID: 40002'};
            app.TextLabel = uilabel(app.GridLayout);
            app.TextLabel.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.SendMethodLabel = uilabel(app.GridLayout7);
            app.SendMethodLabel.Text = 'Send Method:';
            app.TESTButton = uibutton(app.GridLayout, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TestPushed, true);
            app.TESTButton.Text = 'TEST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end